classdef BlackJackGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SinglePlayerButton          matlab.ui.control.Button
        TwoPlayerButton             matlab.ui.control.Button
        BlackjackLabel              matlab.ui.control.Label
        InDevlopmentLabel           matlab.ui.control.Label
        ReturnButton                matlab.ui.control.Button
        Player1Card1                matlab.ui.control.Image
        Player1Card5                matlab.ui.control.Image
        Player1Card4                matlab.ui.control.Image
        Player1Card3                matlab.ui.control.Image
        Player1Card2                matlab.ui.control.Image
        Player2Card1                matlab.ui.control.Image
        Player2Card5                matlab.ui.control.Image
        Player2Card4                matlab.ui.control.Image
        Player2Card3                matlab.ui.control.Image
        Player2Card2                matlab.ui.control.Image
        HitButton                   matlab.ui.control.Button
        StayButton                  matlab.ui.control.Button
        Player1ScoreLabel           matlab.ui.control.Label
        ReturntoMainMenuButton      matlab.ui.control.Button
        Player2ScoreLabel           matlab.ui.control.Label
        HitButton_2                 matlab.ui.control.Button
        StayButton_2                matlab.ui.control.Button
        Winner                      matlab.ui.control.Label
        ScoreBoard                  matlab.ui.control.Label
        PlayAgainButton             matlab.ui.control.StateButton
        QuitButton                  matlab.ui.control.Button
        Player3Card5                matlab.ui.control.Image
        Player3Card1                matlab.ui.control.Image
        Player3Card4                matlab.ui.control.Image
        Player3Card3                matlab.ui.control.Image
        Player3Card2                matlab.ui.control.Image
        Player3ScoreLabel           matlab.ui.control.Label
        HitButton_3                 matlab.ui.control.Button
        StayButton_3                matlab.ui.control.Button
        OnlinePlayTwoPlayerButton   matlab.ui.control.StateButton
        PlayerSelectionButtonGroup  matlab.ui.container.ButtonGroup
        Player1Button               matlab.ui.control.RadioButton
        Player2Button               matlab.ui.control.RadioButton
        Button3                     matlab.ui.control.RadioButton
        RefreshButton               matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        hitButtonState = false % hit for player 1
        stayButtonState % stay for player 1
        hitButton2State % hit for player 2
        stayButton2State % stay for player 2
        playAgain % creates a new hand
        
        playerSelection % Description
    end
    
    methods (Access = private)
        %Creates table for with card names and values
        function [deck] = LoadDeck (~)
            deck = readtable('CardValues.xlsx');
        end
        
        %function shuffles deck/ creates a permuation of 52 numbers from 1 to 52.
        function [shuffledOrder] = ShuffleDeck(~) 
    
            shuffledOrder = randperm(52);

        end
        
        %Gives the player their Hand Value
        function [player1HandValue, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck) 
            player1HandValue = [];
            for i = 1:1:numberOfPlayer1Cards                   
    
                player1HandValue = sum([player1HandValue, deck.CardValues(shuffledOrder(i))]);
    
            end
            app.Player1ScoreLabel.Text = append("Player 1 Hand Value: ", int2str(player1HandValue));
            player2HandValue = [];
            for i = 1:1:numberOfPlayer2Cards                   
    
                player2HandValue = sum([player2HandValue, deck.CardValues(shuffledOrder(i+20))]);
    
            end
            app.Player2ScoreLabel.Text = append("Player 2 Hand Value: ", int2str(player2HandValue));
        end

        %Gives the player their cards *invisable though
        function [player1Cards,player2Cards] = GivePlayerCards(~,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck) 
            player1Cards = [];
            player2Cards = [];
            for i = 1:1:numberOfPlayer1Cards                   
    
                player1Cards = string([player1Cards, deck.CardNames(shuffledOrder(i))]);
    
            end
            for i = 1:1:numberOfPlayer2Cards
                player2Cards = string([player2Cards, deck.CardNames(shuffledOrder(i+20))]);
            end
        end
        
        %can't figure out how to use a string to change a class defination
        %in an app, figure this out later, the method is very ineffienct
        %but im at my wits end
        function [] = UpdatePlayerCards (app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards)
            for i = 1:1:numberOfPlayer1Cards
                cardLocation = char(append(player1Cards(i),".PNG"));
                if i == 1
                    app.Player1Card1.ImageSource = cardLocation;
                    app.Player1Card1.Visible = 'on';
                elseif i == 2
                    app.Player1Card2.ImageSource = cardLocation;
                    app.Player1Card2.Visible = 'on';
                elseif i ==3
                    app.Player1Card3.ImageSource = cardLocation;
                    app.Player1Card3.Visible = 'on';
                elseif i == 4
                    app.Player1Card4.ImageSource = cardLocation;
                    app.Player1Card4.Visible = 'on';
                elseif i == 5
                    app.Player1Card5.ImageSource = cardLocation;
                    app.Player1Card5.Visible = 'on';
                else
                    
                end
            end
            for i = 1:1:numberOfPlayer2Cards
                cardLocation = char(append(player2Cards(i),".PNG"));
                if i == 1
                    app.Player2Card1.ImageSource = cardLocation;
                    app.Player2Card1.Visible = 'on';
                elseif i == 2
                    app.Player2Card2.ImageSource = cardLocation;
                    app.Player2Card2.Visible = 'on';
                elseif i ==3
                    app.Player2Card3.ImageSource = cardLocation;
                    app.Player2Card3.Visible = 'on';
                elseif i == 4
                    app.Player2Card4.ImageSource = cardLocation;
                    app.Player2Card4.Visible = 'on';
                elseif i == 5
                    app.Player1Card5.ImageSource = cardLocation;
                    app.Player1Card5.Visible = 'on';
                else
                    
                end
            end 
        end
        
        %increaments the number of cards by one
        function [numberOfPlayer1Cards] = GivePlayer1CardsPlusOne(~,numberOfPlayer1Cards)
            numberOfPlayer1Cards = numberOfPlayer1Cards + 1;
        end
        function [numberOfPlayer2Cards] = GivePlayer2CardsPlusOne(~,numberOfPlayer2Cards)
            numberOfPlayer2Cards = numberOfPlayer2Cards + 1;
        end
        
        %compares hand values
        function [winner] = CompareScores(~,player1HandValue, player2HandValue)
            if player1HandValue > player2HandValue
                winner = 1;
            elseif player2HandValue > player1HandValue
                winner = 2;
            elseif player1HandValue == player2HandValue
                winner = 0;
            end
        end
        
        
        %Opens Two Player Mode
        function [] = OpenTwoPlayerScreen(app)
            CloseTitleCard(app);
            app.HitButton.Visible = 'on';
            app.HitButton.Enable = 'on';
            app.StayButton.Visible = 'on';
            app.StayButton.Enable = 'on';
            app.Player1ScoreLabel.Visible = 'on';
            
            app.HitButton_2.Visible = 'on';
            app.HitButton_2.Enable = 'on';
            app.StayButton_2.Visible = 'on';
            app.StayButton_2.Enable = 'on';
            app.Player2ScoreLabel.Visible = 'on';
            
            app.ReturntoMainMenuButton.Visible = 'on';
        end
        
        
        %closes Two Player mode
        function [] = CloseTwoPlayerScreen(app)
            app.HitButton.Visible = 'off';
            app.StayButton.Visible = 'off';
            
            app.Player1ScoreLabel.Visible = 'off';
            app.Player1Card1.Visible = 'off';
            app.Player1Card2.Visible = 'off';
            app.Player1Card3.Visible = 'off';
            app.Player1Card4.Visible = 'off';
            app.Player1Card5.Visible = 'off';
            
            app.HitButton_2.Visible = 'off';
            app.StayButton_2.Visible = 'off';
            app.Player2ScoreLabel.Visible = 'off';
            app.Player2Card1.Visible = 'off';
            app.Player2Card2.Visible = 'off';
            app.Player2Card3.Visible = 'off';
            app.Player2Card4.Visible = 'off';
            app.Player2Card5.Visible = 'off';
            
            app.ScoreBoard.Text = "";
            app.Winner.Text = "";
            app.PlayAgainButton.Visible ='off';
            
            app.ReturntoMainMenuButton.Visible = 'off';
            app.RefreshButton.Visible = 'off';


        end
        
        %Turns off the visiblity of the title screen's buttons and labels
        function [] = CloseTitleCard(app)
            app.SinglePlayerButton.Visible = 'off';
            app.TwoPlayerButton.Visible = 'off';
            app.OnlinePlayTwoPlayerButton.Visible = 'off';
            app.QuitButton.Visible = 'off';
            
            
        end
        
        %Turns on the visibility of the 
        function [] = ReturnToTitleCard(app)
            app.SinglePlayerButton.Visible = 'on';
            app.TwoPlayerButton.Visible = 'on';
            app.OnlinePlayTwoPlayerButton.Visible = 'on';
            app.QuitButton.Visible ='on';
            
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SinglePlayerButton
        function StartSinglePlayer(app, event)
            OpenTwoPlayerScreen(app)
            
            app.HitButton_2.Visible = 'off';
            app.StayButton_2.Visible = 'off';
            
            gamestate = true;

            player1Stay = false;
            player2Stay = false;
            
            player1Score = 0;
            player2Score = 0;
            
            overshoot = false;
            
            app.ScoreBoard.Text = append("Scoreboard:  ","Player :  ",int2str(player1Score),"     ","Dealer:  ",int2str(player2Score));
            
            numberOfPlayer1Cards = 2;
            numberOfPlayer2Cards = 2;
            
            
            shuffledOrder = ShuffleDeck(app);
            deck = LoadDeck(app);
            
            winner = 3;
           
 
            while gamestate == true
               
                
                % Updates player cards
               
                [player1Cards,player2Cards] = GivePlayerCards(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                UpdatePlayerCards(app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards);
                [player1HandValue, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                
                %custom code for single player to make player 2 into the
                %dealer
                dealerHandValue = player2HandValue - deck.CardValues(shuffledOrder(21));
                app.Player2ScoreLabel.Text = append("Dealer Hand Value: ", int2str(dealerHandValue), " + ?");
                app.Player2Card1.ImageSource = 'Cardback.png';
                
                %checks if players are overshooting or natural blackjack
                if player1HandValue == 21 && player2HandValue == 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 0;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                
                elseif player1HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 2;
                    overshoot = true;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                elseif player2HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 1;
                    overshoot = true;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                end
                
                % decides who wins and awards points
                if player1Stay == true && player2Stay == true
                    %compares hand values 
                    if overshoot == false    
                        winner = CompareScores(app,player1HandValue, player2HandValue);
                    end
                    if winner == 1
                        app.Winner.Text = "Player Wins";
                        player1Score = player1Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player :  ",int2str(player1Score),"     ","Dealer:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                        
                        %shows player dealer's hand / value
                        UpdatePlayerCards(app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards);
                        [~, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                        app.Player2ScoreLabel.Text = append("Dealer Hand Value: ", int2str(player2HandValue));
                        
                    elseif winner == 2
                        app.Winner.Text = "Dealer Wins";
                        player2Score = player2Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player :  ",int2str(player1Score),"     ","Dealer:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                        
                        %shows player dealer's hand / value
                        UpdatePlayerCards(app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards);
                        [~, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                        app.Player2ScoreLabel.Text = append("Dealer Hand Value: ", int2str(player2HandValue));
                        
                
                    elseif winner == 0
                        app.Winner.Text = "It's a Tie";
                        app.PlayAgainButton.Visible = 'on';
                    end
                    

                end
                
                %dealer logic
                if player2Stay ~= true
                    
                    if player2HandValue <= 17
                        app.hitButton2State = true;
                    elseif player2HandValue > 17
                        app.stayButton2State = true;
                    
                    end
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable = 'on';
                end
                
                
                %waits for a player to hit or stay or new game
                
                
                uiwait(app.UIFigure)
               
                
                if app.playAgain == true
                    app.playAgain = false;

                    player1Stay = false;
                    player2Stay = false;
                    
                    numberOfPlayer1Cards = 2;
                    numberOfPlayer2Cards = 2;
                    
                    overshoot = false;
                    shuffledOrder = ShuffleDeck(app);
                    
                    winner = 3;
                    
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable ='on';
                    
                    app.Player1Card1.Visible = 'off';
                    app.Player1Card2.Visible = 'off';
                    app.Player1Card3.Visible = 'off';
                    app.Player1Card4.Visible = 'off';
                    app.Player1Card5.Visible = 'off';
                    
                    
                    app.Player2Card1.Visible = 'off';
                    app.Player2Card2.Visible = 'off';
                    app.Player2Card3.Visible = 'off';
                    app.Player2Card4.Visible = 'off';
                    app.Player2Card5.Visible = 'off';
                    
                    
                    app.PlayAgainButton.Visible ='off';
                    app.Winner.Text = '';
                end
                
                %decision making when a hit or stay button is pushed
                if app.hitButtonState == true
                    numberOfPlayer1Cards = GivePlayer1CardsPlusOne(app,numberOfPlayer1Cards);
                    app.hitButtonState = false;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';

                elseif app.stayButtonState == true
                    player1Stay = true;
                    app.stayButtonState = false;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    
                end 
                
                if app.hitButton2State == true
                    numberOfPlayer2Cards = GivePlayer2CardsPlusOne(app,numberOfPlayer2Cards);
                    app.hitButton2State = false;

                
                elseif app.stayButton2State == true
                    player2Stay = true;
                    app.stayButton2State = false;

                end
                
            end
        end

        % Button pushed function: TwoPlayerButton
        function StartTwoPlayer(app, event)
            CloseTitleCard(app)
            OpenTwoPlayerScreen(app)
            
            gamestate = true;
            playerTurn = 1;
            player1Stay = false;
            player2Stay = false;
            player1Score = 0;
            player2Score = 0;
            overshoot = false;
            app.ScoreBoard.Text = append("Scoreboard:  ","Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
            numberOfPlayer1Cards = 2;
            numberOfPlayer2Cards = 2;
            shuffledOrder = ShuffleDeck(app);
            deck = LoadDeck(app);
            
            while gamestate == true
                
                
                % Updates player cards
               
                [player1Cards,player2Cards] = GivePlayerCards(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                UpdatePlayerCards(app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards);
                [player1HandValue, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                
                %checks if players are able to losing or wining or a tie
                if player1HandValue == 21 && player2HandValue == 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 0;
                   app.HitButton.Enable = 'off';
                   app.StayButton.Enable = 'off';
                   app.HitButton_2.Enable = 'off';
                   app.StayButton_2.Enable = 'off';
                elseif player1HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 2;
                    overshoot = true;
                   app.HitButton.Enable = 'off';
                   app.StayButton.Enable = 'off';
                   app.HitButton_2.Enable = 'off';
                   app.StayButton_2.Enable = 'off';
                elseif player2HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 1;
                    overshoot = true;
                   app.HitButton.Enable = 'off';
                   app.StayButton.Enable = 'off';
                   app.HitButton_2.Enable = 'off';
                   app.StayButton_2.Enable = 'off';
                end
                
                % enables or disables hit and stay buttons based on player
                % turn
                if player1Stay == true && player2Stay == true
                    if overshoot == false    
                        winner = CompareScores(app,player1HandValue, player2HandValue);
                    end
                    if winner == 1
                        app.Winner.Text = "Player 1 Wins";
                        player1Score = player1Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                    elseif winner == 2
                        app.Winner.Text = "Player 2 Wins";
                        player2Score = player2Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                    elseif winner == 0
                        app.Winner.Text = "It's a Tie";
                        app.PlayAgainButton.Visible = 'on';
                    end
                elseif playerTurn == 1 && player1Stay == false
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable = 'on';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                    if player1Stay == true
                        playerTurn = 2;
                    end
                    
                
                elseif playerTurn == 2 && player2Stay == false
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'on';
                    app.StayButton_2.Enable = 'on';
                    if player2Stay == true
                        playerTurn = 1;
                    end
                end
                
                
                %waits for a player to hit or stay or new game
                uiwait(app.UIFigure)
                
                if app.playAgain == true
                    app.playAgain = false;
                    if winner == 1
                        playerTurn = 2;
                    elseif winner == 2
                        playerTurn = 1;
                    end
                    player1Stay = false;
                    player2Stay = false;
                    numberOfPlayer1Cards = 2;
                    numberOfPlayer2Cards = 2;
                    overshoot = false;
                    shuffledOrder = ShuffleDeck(app);
                    
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable = 'on';
                    
                    app.Player1Card1.Visible = 'off';
                    app.Player1Card2.Visible = 'off';
                    app.Player1Card3.Visible = 'off';
                    app.Player1Card4.Visible = 'off';
                    app.Player1Card5.Visible = 'off';
                    
                    
                    app.Player2Card1.Visible = 'off';
                    app.Player2Card2.Visible = 'off';
                    app.Player2Card3.Visible = 'off';
                    app.Player2Card4.Visible = 'off';
                    app.Player2Card5.Visible = 'off';
                    
                    
                    app.PlayAgainButton.Visible ='off';
                    app.Winner.Text = '';
                end
                
                %decision making when a hit or stay button is pushed
                if app.hitButtonState == true
                    numberOfPlayer1Cards = GivePlayer1CardsPlusOne(app,numberOfPlayer1Cards);
                    app.hitButtonState = false;
                    playerTurn = 2;
                
                elseif app.stayButtonState == true
                    player1Stay = true;
                    app.stayButtonState = false;
                    playerTurn = 2;
                
                elseif app.hitButton2State == true
                    numberOfPlayer2Cards = GivePlayer2CardsPlusOne(app,numberOfPlayer2Cards);
                    app.hitButton2State = false;
                    playerTurn = 1;
                
                elseif app.stayButton2State == true
                    player2Stay = true;
                    app.stayButton2State = false;
                    playerTurn = 1;
                end
                
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.SinglePlayerButton.Visible = 'on';
            app.TwoPlayerButton.Visible = 'on';
            app.ThreePlayertestButton.Visible = 'on';
            app.QuitButton.Visible = 'on';
           
            app.InDevlopmentLabel.Visible = 'off';
            app.ReturnButton.Visible = 'off';
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
           app.hitButtonState = true;
           uiresume(app.UIFigure)
        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            app.stayButtonState = true;
            uiresume(app.UIFigure)
        end

        % Button pushed function: ReturntoMainMenuButton
        function ReturntoMainMenuButtonPushed(app, event)
            ReturnToTitleCard(app)
            CloseTwoPlayerScreen(app);
            
        end

        % Button pushed function: HitButton_2
        function HitButton_2Pushed(app, event)
            app.hitButton2State = true;
            uiresume(app.UIFigure)
        end

        % Button pushed function: StayButton_2
        function StayButton_2Pushed(app, event)
            app.stayButton2State = true;
            uiresume(app.UIFigure)
        end

        % Value changed function: PlayAgainButton
        function PlayAgainButtonValueChanged(app, event)
            app.playAgain = true;
            uiresume(app.UIFigure)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: OnlinePlayTwoPlayerButton
        function OnlinePlayTwoPlayerButtonValueChanged(app, event)
            CloseTitleCard(app);
            
            app.PlayerSelectionButtonGroup.Visible ='on';
            uiwait(app.UIFigure);
            app.PlayerSelectionButtonGroup.Visible ='off';
            app.RefreshButton.Visible ='on';
            OpenTwoPlayerScreen(app)
            
            gamestate = true;
            playerTurn = 1;
            player1Stay = false;
            player2Stay = false;
            playerHitsStays = [false,false,false,false];
            player1Score = 0;
            player2Score = 0;
            overshoot = false;
            app.ScoreBoard.Text = append("Scoreboard:  ","Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
            numberOfPlayer1Cards = 2;
            numberOfPlayer2Cards = 2;
            
            if app.playerSelection == 1
            shuffledOrder = ShuffleDeck(app);
            
            %sends data to thinkspeak
            player1CardsIndex = shuffledOrder(1:5);
            player2CardsIndex = shuffledOrder(20:25);
            
            thingSpeakWrite(1325486,player1CardsIndex,'WriteKey','22FKNHKTMEH5LZ4Y');
            thingSpeakWrite(1322871,player2CardsIndex,'WriteKey','FT22XUC4YDMLC68S');
            
            %turns off player 2 buttons
            app.HitButton_2.Visible = 'off';
            app.StayButton_2.Visible = 'off';
            
            end
            
            if app.playerSelection == 2
                app.HitButton.Visible ='off';
                app.StayButton.Visible ='off';
            end

            deck = LoadDeck(app);
            
            while gamestate == true


                if app.playerSelection == 2
                %reads data from thinkspeak
                player1CardsIndex = thingSpeakRead(1325486,'ReadKey','JBP9ZNMFKA1DC9ZS');
                player2CardsIndex = thingSpeakRead(1322871,'ReadKey','E0WEI8QR51LXIP3K');
                end


                % Updates player cards
               
                player1Cards = [];
                player2Cards = [];
                for i = 1:1:numberOfPlayer1Cards                   
    
                    player1Cards = string([player1Cards, deck.CardNames(player1CardsIndex(i))]);
    
                end
                for i = 1:1:numberOfPlayer2Cards
                    player2Cards = string([player2Cards, deck.CardNames(player2CardsIndex(i))]);
                end
                
                %updates visuals for the cards
                UpdatePlayerCards(app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards);
                
                %updates player hand values
                player1HandValue = [];
                for i = 1:1:numberOfPlayer1Cards                   
    
                    player1HandValue = sum([player1HandValue, deck.CardValues(player1CardsIndex(i))]);
    
                end
                
                app.Player1ScoreLabel.Text = append("Player 1 Hand Value: ", int2str(player1HandValue));
                player2HandValue = [];
                
                for i = 1:1:numberOfPlayer2Cards                   
    
                    player2HandValue = sum([player2HandValue, deck.CardValues(player2CardsIndex(i))]);
    
                end
                app.Player2ScoreLabel.Text = append("Player 2 Hand Value: ", int2str(player2HandValue));
                
                %checks if players are able to losing or wining or a tie
                if player1HandValue == 21 && player2HandValue == 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 0;
                   app.HitButton.Enable = 'off';
                   app.StayButton.Enable = 'off';
                   app.HitButton_2.Enable = 'off';
                   app.StayButton_2.Enable = 'off';
                elseif player1HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 2;
                    overshoot = true;
                   app.HitButton.Enable = 'off';
                   app.StayButton.Enable = 'off';
                   app.HitButton_2.Enable = 'off';
                   app.StayButton_2.Enable = 'off';
                elseif player2HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 1;
                    overshoot = true;
                   app.HitButton.Enable = 'off';
                   app.StayButton.Enable = 'off';
                   app.HitButton_2.Enable = 'off';
                   app.StayButton_2.Enable = 'off';
                end
                
                % enables or disables hit and stay buttons based on player
                % turn
                if player1Stay == true && player2Stay == true
                    if overshoot == false    
                        winner = CompareScores(app,player1HandValue, player2HandValue);
                    end
                    if winner == 1
                        app.Winner.Text = "Player 1 Wins";
                        player1Score = player1Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                    elseif winner == 2
                        app.Winner.Text = "Player 2 Wins";
                        player2Score = player2Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                    elseif winner == 0
                        app.Winner.Text = "It's a Tie";
                        app.PlayAgainButton.Visible = 'on';
                    end
                elseif playerTurn == 1 && player1Stay == false
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable = 'on';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                    if player1Stay == true
                        playerTurn = 2;
                    end
                    
                
                elseif playerTurn == 2 && player2Stay == false
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'on';
                    app.StayButton_2.Enable = 'on';
                    if player2Stay == true
                        playerTurn = 1;
                    end
                end


                %waits for a player to hit or stay or new game
                uiwait(app.UIFigure)


                % think speak data is being sent depending on player
                if app.playerSelection == 2
                    if app.hitButton2State == true
                        playerHitsStays(3) = true;
                        
                    elseif app.stayButton2State == true
                        playerHitsStays(4) = true;
                        
                    end
                end
                
                if app.playerSelection == 1
                    if app.hitButtonState == true
                        playerHitsStays(1) = true;
                        
                    elseif app.stayButtonState == true
                        playerHitsStays(2) = true;
                        
                    end
                    
                end
                
                thingSpeakWrite(1325535,playerHitsStays,'WriteKey','QDXWJUOF4Q3OYLIC');
                %pause(15);
                playerHitsStays = thingSpeakRead(1325535,'ReadKey','DVMR74DFWLJXU4J5');
                
                if app.playerSelection == 2
                    app.hitButtonState = playerHitsStays(1);
                    app.stayButtonState = playerHitsStays(2);
                end
                
                if app.playerSelection == 1
                    app.hitButton2State = playerHitsStays(3);
                    app.stayButton2State = playerHitsStays(4);
                end
                
                
                %function if play again button is clicked
                if app.playAgain == true
                    app.playAgain = false;
                    if winner == 1
                        playerTurn = 2;
                    elseif winner == 2
                        playerTurn = 1;
                    end
                    player1Stay = false;
                    player2Stay = false;
                    numberOfPlayer1Cards = 2;
                    numberOfPlayer2Cards = 2;
                    overshoot = false;
                    if app.playerSelection == 1
                    shuffledOrder = ShuffleDeck(app);
            
                    %sends data to thinkspeak
                    player1CardsIndex = shuffledOrder(1:5);
                    player2CardsIndex = shuffledOrder(20:25);
            
                    thingSpeakWrite(1325486,player1CardsIndex,'WriteKey','22FKNHKTMEH5LZ4Y');
                    thingSpeakWrite(1322871,player2CardsIndex,'WriteKey','FT22XUC4YDMLC68S');
                    
                    playerHitsStays = [false,false,false,false];
                    end
                  
                    
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable = 'on';
                    
                    app.Player1Card1.Visible = 'off';
                    app.Player1Card2.Visible = 'off';
                    app.Player1Card3.Visible = 'off';
                    app.Player1Card4.Visible = 'off';
                    app.Player1Card5.Visible = 'off';
                    
                    
                    app.Player2Card1.Visible = 'off';
                    app.Player2Card2.Visible = 'off';
                    app.Player2Card3.Visible = 'off';
                    app.Player2Card4.Visible = 'off';
                    app.Player2Card5.Visible = 'off';
                    
                    
                    app.PlayAgainButton.Visible ='off';
                    app.Winner.Text = '';
                end
                
                %decision making when a hit or stay button is pushed
                if app.hitButtonState == true
                    numberOfPlayer1Cards = GivePlayer1CardsPlusOne(app,numberOfPlayer1Cards);
                    app.hitButtonState = false;
                    playerTurn = 2;
                    
                    
                elseif app.stayButtonState == true
                    player1Stay = true;
                    app.stayButtonState = false;
                    playerTurn = 2;
                    
                end    
                
                if app.hitButton2State == true
                    numberOfPlayer2Cards = GivePlayer2CardsPlusOne(app,numberOfPlayer2Cards);
                    app.hitButton2State = false;
                    playerTurn = 1;
                
                elseif app.stayButton2State == true
                    player2Stay = true;
                    app.stayButton2State = false;
                    playerTurn = 1;
                end
                
            end
            
        end

        % Selection changed function: PlayerSelectionButtonGroup
        function PlayerSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlayerSelectionButtonGroup.SelectedObject;
            
            if selectedButton == app.Player1Button
                app.playerSelection = 1;
            elseif selectedButton == app.Player2Button
                app.playerSelection = 2;
            end
            uiresume(app.UIFigure)
        end

        % Value changed function: RefreshButton
        function RefreshButtonValueChanged(app, event)
            uiresume(app.UIFigure)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SinglePlayerButton = uibutton(app.UIFigure, 'push');
            app.SinglePlayerButton.ButtonPushedFcn = createCallbackFcn(app, @StartSinglePlayer, true);
            app.SinglePlayerButton.Text = {'Single Player'; ''};
            app.TwoPlayerButton = uibutton(app.UIFigure, 'push');
            app.TwoPlayerButton.ButtonPushedFcn = createCallbackFcn(app, @StartTwoPlayer, true);
            app.TwoPlayerButton.Text = {'Two Player'; ''};
            app.BlackjackLabel = uilabel(app.UIFigure);
            app.BlackjackLabel.Tag = 'TitleCard';
            app.BlackjackLabel.Text = 'Blackjack';
            app.InDevlopmentLabel = uilabel(app.UIFigure);
            app.InDevlopmentLabel.Visible = 'off';
            app.InDevlopmentLabel.Text = 'In Devlopment';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Visible = 'off';
            app.ReturnButton.Text = 'Return';
            app.Player1Card1 = uiimage(app.UIFigure);
            app.Player1Card1.Visible = 'off';
            app.Player1Card5 = uiimage(app.UIFigure);
            app.Player1Card5.Visible = 'off';
            app.Player1Card4 = uiimage(app.UIFigure);
            app.Player1Card4.Visible = 'off';
            app.Player1Card3 = uiimage(app.UIFigure);
            app.Player1Card3.Visible = 'off';
            app.Player1Card2 = uiimage(app.UIFigure);
            app.Player1Card2.Visible = 'off';
            app.Player2Card1 = uiimage(app.UIFigure);
            app.Player2Card1.Visible = 'off';
            app.Player2Card5 = uiimage(app.UIFigure);
            app.Player2Card5.Visible = 'off';
            app.Player2Card4 = uiimage(app.UIFigure);
            app.Player2Card4.Visible = 'off';
            app.Player2Card3 = uiimage(app.UIFigure);
            app.Player2Card3.Visible = 'off';
            app.Player2Card2 = uiimage(app.UIFigure);
            app.Player2Card2.Visible = 'off';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Visible = 'off';
            app.HitButton.Text = 'Hit';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Visible = 'off';
            app.StayButton.Text = 'Stay';
            app.Player1ScoreLabel = uilabel(app.UIFigure);
            app.Player1ScoreLabel.Visible = 'off';
            app.Player1ScoreLabel.Text = 'Player 1 Score: ';
            app.ReturntoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMainMenuButtonPushed, true);
            app.ReturntoMainMenuButton.Visible = 'off';
            app.ReturntoMainMenuButton.Text = 'Return to Main Menu';
            app.Player2ScoreLabel = uilabel(app.UIFigure);
            app.Player2ScoreLabel.Visible = 'off';
            app.Player2ScoreLabel.Text = 'Player 2 Score: ';
            app.HitButton_2 = uibutton(app.UIFigure, 'push');
            app.HitButton_2.ButtonPushedFcn = createCallbackFcn(app, @HitButton_2Pushed, true);
            app.HitButton_2.Visible = 'off';
            app.HitButton_2.Text = 'Hit';
            app.StayButton_2 = uibutton(app.UIFigure, 'push');
            app.StayButton_2.ButtonPushedFcn = createCallbackFcn(app, @StayButton_2Pushed, true);
            app.StayButton_2.Visible = 'off';
            app.StayButton_2.Text = 'Stay';
            app.Winner = uilabel(app.UIFigure);
            app.Winner.Text = '';
            app.ScoreBoard = uilabel(app.UIFigure);
            app.ScoreBoard.Text = '';
            app.PlayAgainButton = uibutton(app.UIFigure, 'state');
            app.PlayAgainButton.ValueChangedFcn = createCallbackFcn(app, @PlayAgainButtonValueChanged, true);
            app.PlayAgainButton.Visible = 'off';
            app.PlayAgainButton.Text = {'Play Again?'; ''};
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = {'Quit'; ''};
            app.Player3Card5 = uiimage(app.UIFigure);
            app.Player3Card5.Visible = 'off';
            app.Player3Card1 = uiimage(app.UIFigure);
            app.Player3Card1.Visible = 'off';
            app.Player3Card4 = uiimage(app.UIFigure);
            app.Player3Card4.Visible = 'off';
            app.Player3Card3 = uiimage(app.UIFigure);
            app.Player3Card3.Visible = 'off';
            app.Player3Card2 = uiimage(app.UIFigure);
            app.Player3Card2.Visible = 'off';
            app.Player3ScoreLabel = uilabel(app.UIFigure);
            app.Player3ScoreLabel.Visible = 'off';
            app.Player3ScoreLabel.Text = 'Player 2 Score: ';
            app.HitButton_3 = uibutton(app.UIFigure, 'push');
            app.HitButton_3.Visible = 'off';
            app.HitButton_3.Text = 'Hit';
            app.StayButton_3 = uibutton(app.UIFigure, 'push');
            app.StayButton_3.Visible = 'off';
            app.StayButton_3.Text = 'Stay';
            app.OnlinePlayTwoPlayerButton = uibutton(app.UIFigure, 'state');
            app.OnlinePlayTwoPlayerButton.ValueChangedFcn = createCallbackFcn(app, @OnlinePlayTwoPlayerButtonValueChanged, true);
            app.OnlinePlayTwoPlayerButton.Text = {'Online Play'; '(Two Player)'};
            app.PlayerSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.PlayerSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlayerSelectionButtonGroupSelectionChanged, true);
            app.PlayerSelectionButtonGroup.Title = 'Player Selection';
            app.PlayerSelectionButtonGroup.Visible = 'off';
            app.Player1Button = uiradiobutton(app.PlayerSelectionButtonGroup);
            app.Player1Button.Text = 'Player 1';
            app.Player2Button = uiradiobutton(app.PlayerSelectionButtonGroup);
            app.Player2Button.Text = 'Player 2';
            app.Button3 = uiradiobutton(app.PlayerSelectionButtonGroup);
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button3.Value = true;
            app.RefreshButton = uibutton(app.UIFigure, 'state');
            app.RefreshButton.ValueChangedFcn = createCallbackFcn(app, @RefreshButtonValueChanged, true);
            app.RefreshButton.Visible = 'off';
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackJackGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end